classdef Simulasi1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image                          matlab.ui.control.Image
        ExitButton                     matlab.ui.control.Button
        HomeButton                     matlab.ui.control.Button
        ProcessButton                  matlab.ui.control.Button
        RayleighFadingLabel_4          matlab.ui.control.Label
        RicianFadingLabel              matlab.ui.control.Label
        RayleighFadingLabel_2          matlab.ui.control.Label
        RayleighFadingLabel            matlab.ui.control.Label
        InputPanel                     matlab.ui.container.Panel
        BitsperFrameEditField          matlab.ui.control.NumericEditField
        BitsperFrameEditFieldLabel     matlab.ui.control.Label
        SpectrumDopplerShiftEditField  matlab.ui.control.NumericEditField
        SpectrumDopplerShiftEditFieldLabel  matlab.ui.control.Label
        KFactorEditField               matlab.ui.control.NumericEditField
        KFactorEditFieldLabel          matlab.ui.control.Label
        MaxDopplerShiftEditField       matlab.ui.control.NumericEditField
        MaxDopplerShiftEditFieldLabel  matlab.ui.control.Label
        SampleRate2HzEditField         matlab.ui.control.NumericEditField
        SampleRate2HzEditFieldLabel    matlab.ui.control.Label
        SampleRate1HzEditField         matlab.ui.control.NumericEditField
        SampleRate1HzEditFieldLabel    matlab.ui.control.Label
        MULTIPATHFADINGRayleighandRicianMultipathFadingLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SampleRate1HzEditField
        function SampleRate1HzEditFieldValueChanged(app, event)

        end

        % Value changed function: SampleRate2HzEditField
        function SampleRate2HzEditFieldValueChanged(app, event)

        end

        % Value changed function: MaxDopplerShiftEditField
        function MaxDopplerShiftEditFieldValueChanged(app, event)

        end

        % Value changed function: KFactorEditField
        function KFactorEditFieldValueChanged(app, event)

        end

        % Value changed function: SpectrumDopplerShiftEditField
        function SpectrumDopplerShiftEditFieldValueChanged(app, event)

        end

        % Value changed function: BitsperFrameEditField
        function BitsperFrameEditFieldValueChanged(app, event)
           
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
%Initialization
sampleRate1 = app.SampleRate1HzEditField.Value;
sampleRate2 = app.SampleRate2HzEditField.Value;
delayVector = (0:5:15)*1e-6; % Discrete delays of four-path channel (s)
gainVector  = [0 -3 -6 -9]; % Average path gains (dB)

KFactor = app.KFactorEditField.Value;
maxDopplerShift = app.MaxDopplerShiftEditField.Value;
specDopplerShift = app.SpectrumDopplerShiftEditField.Value;

%Creating Channel System Objects
% Configure a Rayleigh channel object
rayChan = comm.RayleighChannel( ...
    'SampleRate',sampleRate1, ...
    'PathDelays',delayVector, ...
    'AveragePathGains',gainVector, ...
    'MaximumDopplerShift',maxDopplerShift, ...
    'RandomStream','mt19937ar with seed', ...
    'Seed',10, ...
    'PathGainsOutputPort',true);

% Configure a Rician channel object
ricChan = comm.RicianChannel( ...
    'SampleRate',sampleRate1, ...
    'PathDelays',delayVector, ...
    'AveragePathGains',gainVector, ...
    'KFactor',KFactor, ...
    'DirectPathDopplerShift',specDopplerShift, ...
    'MaximumDopplerShift',maxDopplerShift, ...
    'RandomStream','mt19937ar with seed', ...
    'Seed',100, ...
    'PathGainsOutputPort',true);

%Modulation and Channel FILTERING
qpskMod = comm.QPSKModulator('BitInput',true,'PhaseOffset',pi/4);

% Number of bits transmitted per frame is set to be 1000. For QPSK
% modulation, this corresponds to 500 symbols per frame.
bitsPerFrame = app.BitsperFrameEditField.Value;
msg = randi([0 1],bitsPerFrame,1);

% Modulate data for transmission over channel
modSignal = qpskMod(msg);

% Apply Rayleigh or Rician channel object on the modulated data
rayChan(modSignal);
ricChan(modSignal);

%Visualization
release(rayChan);
release(ricChan);

%Wideband or Freqeuncy-Selevtive Fading

rayChan.Visualization = 'Impulse and frequency responses';
rayChan.SamplesToDisplay = '100%';

% Display impulse and frequency responses for 2 frames
numFrames = 2;
for i = 1:numFrames
    % Create random data
    msg = randi([0 1],bitsPerFrame,1);
    % Modulate data
    modSignal = qpskMod(msg);
    % Filter data through channel and show channel responses
    rayChan(modSignal);
end

release(rayChan);
rayChan.Visualization = 'Doppler spectrum';

% Display Doppler spectrum from 5000 frame transmission
numFrames = 5000;
for i = 1:numFrames
    msg = randi([0 1],bitsPerFrame,1);
    modSignal = qpskMod(msg);
    rayChan(modSignal);
end

%Narrowband or Frequency-Flat Fading
release(rayChan);
rayChan.Visualization = 'Impulse and frequency responses';
rayChan.SampleRate = sampleRate2;
rayChan.SamplesToDisplay = '25%';  % Display one of every four samples

% Display impulse and frequency responses for 2 frames
numFrames = 2;
for i = 1:numFrames
    msg = randi([0 1],bitsPerFrame,1);
    modSignal = qpskMod(msg);
    rayChan(modSignal);
end

release(rayChan);
rayChan.PathDelays = 0;        % Single fading path with zero delay
rayChan.AveragePathGains = 0;  % Average path gain of 1 (0 dB)

for i = 1:numFrames % Display impulse and frequency responses for 2 frames
    msg = randi([0 1],bitsPerFrame,1);
    modSignal = qpskMod(msg);
    rayChan(modSignal);
end

release(rayChan);
rayChan.Visualization = 'Off'; % Turn off Rayliegh object visualization
ricChan.Visualization = 'Off'; % Turn off Rician object visualization

% Same sample rate and delay profile for the Rayleigh and Rician objects
ricChan.SampleRate = rayChan.SampleRate;
ricChan.PathDelays = rayChan.PathDelays;
ricChan.AveragePathGains = rayChan.AveragePathGains;

% Configure a Time Scope System object to show path gain magnitude
gainScope = timescope( ...
    'SampleRate',rayChan.SampleRate, ...
    'TimeSpanSource','Property',...
    'TimeSpan',bitsPerFrame/2/rayChan.SampleRate, ... % One frame span
    'Name','Multipath Gain', ...
    'ShowGrid',true, ...
    'YLimits',[-40 10], ...
    'YLabel','Gain (dB)');

% Compare the path gain outputs from both objects for one frame
msg = randi([0 1],bitsPerFrame,1);
modSignal = qpskMod(msg);
[~,rayPathGain] = rayChan(modSignal);
[~,ricPathGain] = ricChan(modSignal);
% Form the path gains as a two-channel input to the time scope
gainScope(10*log10(abs([rayPathGain,ricPathGain]).^2));

clear hRicChan hMultipathGain;
release(rayChan);

rayChan.PathDelays = delayVector;
rayChan.AveragePathGains = gainVector;
rayChan.MaximumDopplerShift = 5;

% Configure a Constellation Diagram System object to show received signal
constDiag = comm.ConstellationDiagram( ...
    'Name','Received Signal After Rayleigh Fading');

numFrames = 16;

for n = 1:numFrames
    msg = randi([0 1],bitsPerFrame,1);
    modSignal = qpskMod(msg);
    rayChanOut = rayChan(modSignal);
    % Display constellation diagram for Rayleigh channel output
    constDiag(rayChanOut);
end

release(rayChan);
release(constDiag);
rayChan.SampleRate = sampleRate1;

for n = 1:numFrames
    msg = randi([0 1],bitsPerFrame,1);
    modSignal = qpskMod(msg);
    rayChanOut = rayChan(modSignal);
    constDiag(rayChanOut);
end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq()
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            run SlideMenu;
            closereq()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MULTIPATHFADINGRayleighandRicianMultipathFadingLabel = uilabel(app.UIFigure);
            app.MULTIPATHFADINGRayleighandRicianMultipathFadingLabel.Text = {'MULTIPATH FADING:'; 'Rayleigh and Rician Multipath Fading'};
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.SampleRate1HzEditFieldLabel = uilabel(app.InputPanel);
            app.SampleRate1HzEditFieldLabel.Text = 'Sample Rate 1 (Hz)';
            app.SampleRate1HzEditField = uieditfield(app.InputPanel, 'numeric');
            app.SampleRate1HzEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRate1HzEditFieldValueChanged, true);
            app.SampleRate1HzEditField.Value = 300000;
            app.SampleRate2HzEditFieldLabel = uilabel(app.InputPanel);
            app.SampleRate2HzEditFieldLabel.Text = 'Sample Rate 2 (Hz)';
            app.SampleRate2HzEditField = uieditfield(app.InputPanel, 'numeric');
            app.SampleRate2HzEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRate2HzEditFieldValueChanged, true);
            app.SampleRate2HzEditField.Value = 15000;
            app.MaxDopplerShiftEditFieldLabel = uilabel(app.InputPanel);
            app.MaxDopplerShiftEditFieldLabel.Text = 'Max. Doppler Shift';
            app.MaxDopplerShiftEditField = uieditfield(app.InputPanel, 'numeric');
            app.MaxDopplerShiftEditField.ValueChangedFcn = createCallbackFcn(app, @MaxDopplerShiftEditFieldValueChanged, true);
            app.MaxDopplerShiftEditField.Value = 150;
            app.KFactorEditFieldLabel = uilabel(app.InputPanel);
            app.KFactorEditFieldLabel.Text = 'K Factor';
            app.KFactorEditField = uieditfield(app.InputPanel, 'numeric');
            app.KFactorEditField.ValueChangedFcn = createCallbackFcn(app, @KFactorEditFieldValueChanged, true);
            app.KFactorEditField.Value = 10;
            app.SpectrumDopplerShiftEditFieldLabel = uilabel(app.InputPanel);
            app.SpectrumDopplerShiftEditFieldLabel.Text = 'Spectrum Doppler Shift';
            app.SpectrumDopplerShiftEditField = uieditfield(app.InputPanel, 'numeric');
            app.SpectrumDopplerShiftEditField.ValueChangedFcn = createCallbackFcn(app, @SpectrumDopplerShiftEditFieldValueChanged, true);
            app.SpectrumDopplerShiftEditField.Value = 100;
            app.BitsperFrameEditFieldLabel = uilabel(app.InputPanel);
            app.BitsperFrameEditFieldLabel.Text = 'Bits per Frame';
            app.BitsperFrameEditField = uieditfield(app.InputPanel, 'numeric');
            app.BitsperFrameEditField.ValueChangedFcn = createCallbackFcn(app, @BitsperFrameEditFieldValueChanged, true);
            app.BitsperFrameEditField.Value = 2000;
            app.RayleighFadingLabel = uilabel(app.UIFigure);
            app.RayleighFadingLabel.Text = {'Rayleigh Fading:'; ''};
            app.RayleighFadingLabel_2 = uilabel(app.UIFigure);
            app.RayleighFadingLabel_2.Text = {'Pada kanal komunikasi bergerak, distribusi Rayleigh'; 'biasa digunakan untuk menjelaskan perubahan waktu'; 'dari selubung sinyal fading datar (flat fading) yang'; 'diterima, atau selubung dari satu komponen multipath.'};
            app.RicianFadingLabel = uilabel(app.UIFigure);
            app.RicianFadingLabel.Text = {'Rician Fading:'; ''};
            app.RayleighFadingLabel_4 = uilabel(app.UIFigure);
            app.RayleighFadingLabel_4.Text = {'Rician fading atau Ricean fading adalah model stokastik'; 'untuk anomali propagasi radio yang disebabkan'; 'oleh pembatalan sebagian sinyal radio dengan sendirinya'; '— sinyal tiba di penerima melalui beberapa jalur berbeda'; '(karenanya menunjukkan interferensi multipath),'; 'dan setidaknya salah satu jalur berubah'; '(memperpanjang atau memperpendek)'};
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo-UI.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simulasi1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end